classdef guimain < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GUIMain     matlab.ui.Figure
        MenuFile    matlab.ui.container.Menu
        Map         matlab.ui.control.UIAxes
        DebugLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        cbMouseWheelCount   = 0
        mapFolder           = 'D:\GitHub\LeONAR_dev\tiles\' % temporary
        mapCurrentZoom
        mapMinZoom
        mapMaxZoom
        mapClicked          = false
        mapClickedCoords    = [0 0];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            [app.mapMinZoom,app.mapMaxZoom] = GetMinMaxZoomFromTilesFolder(app.mapFolder);
            
            % Starting fully zoomed out
            % (TODO: temporary, waiting for user configuration file storing last zoom level)
            app.mapCurrentZoom = app.mapMinZoom;
        end

        % Window button down function: GUIMain
        function GUIMainWindowButtonDownFcn(app, event)
            [source,coords_source,~] = leonar_WindowButtonDownFcn(app,event);
            switch source
                case 'Map'
                    app.mapClicked          = true;
                    app.mapClickedCoords    = coords_source;
                    app.DebugLabel.Text = num2str(app.mapClickedCoords);
            end
        end

        % Window scroll wheel function: GUIMain
        function GUIMainWindowScrollWheel(app, event)
            app.cbMouseWheelCount = leonar_WindowScrollWheel(app,event);
            app.mapCurrentZoom = max(min(app.mapCurrentZoom - app.cbMouseWheelCount,app.mapMaxZoom),app.mapMinZoom);
            app.DebugLabel.Text = num2str(app.mapCurrentZoom);
        end

        % Window button up function: GUIMain
        function GUIMainWindowButtonUp(app, event)
            app.mapClicked = false;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GUIMain = uifigure('Visible', 'off');
            app.GUIMain.Name = 'LeONAR';
            app.GUIMain.WindowButtonDownFcn = createCallbackFcn(app, @GUIMainWindowButtonDownFcn, true);
            app.GUIMain.WindowButtonUpFcn = createCallbackFcn(app, @GUIMainWindowButtonUp, true);
            app.GUIMain.WindowScrollWheelFcn = createCallbackFcn(app, @GUIMainWindowScrollWheel, true);
            app.MenuFile = uimenu(app.GUIMain);
            app.MenuFile.Text = 'File';
            app.Map = uiaxes(app.GUIMain);
            title(app.Map, '')
            xlabel(app.Map, '')
            ylabel(app.Map, '')
            app.Map.XTick = [];
            app.Map.XTickLabel = '';
            app.Map.YTick = [];
            app.DebugLabel = uilabel(app.GUIMain);
            app.DebugLabel.Text = 'Debug';
            % Show the figure after all components are created
            app.GUIMain.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = guimain

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GUIMain)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GUIMain)
        end
    end
end